classdef oven_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Slot1CheckBox               matlab.ui.control.CheckBox
        Slot2CheckBox               matlab.ui.control.CheckBox
        Slot3CheckBox               matlab.ui.control.CheckBox
        Slot4CheckBox               matlab.ui.control.CheckBox
        Slot5CheckBox               matlab.ui.control.CheckBox
        Slot6CheckBox               matlab.ui.control.CheckBox
        Slot7CheckBox               matlab.ui.control.CheckBox
        Slot8CheckBox               matlab.ui.control.CheckBox
        Slot9CheckBox               matlab.ui.control.CheckBox
        Slot10CheckBox              matlab.ui.control.CheckBox
        Slot11CheckBox              matlab.ui.control.CheckBox
        Slot12CheckBox              matlab.ui.control.CheckBox
        CupcakeColourDropDownLabel  matlab.ui.control.Label
        CupcakeColourDropDown       matlab.ui.control.DropDown
        OvenAssitaBotLabel          matlab.ui.control.Label
        Joint1SliderLabel           matlab.ui.control.Label
        Joint1Slider                matlab.ui.control.Slider
        Joint2SliderLabel           matlab.ui.control.Label
        Joint2Slider                matlab.ui.control.Slider
        Joint3SliderLabel           matlab.ui.control.Label
        Joint3Slider                matlab.ui.control.Slider
        Joint4SliderLabel           matlab.ui.control.Label
        Joint4Slider                matlab.ui.control.Slider
        Joint5SliderLabel           matlab.ui.control.Label
        Joint5Slider                matlab.ui.control.Slider
        Joint6SliderLabel           matlab.ui.control.Label
        Joint6Slider                matlab.ui.control.Slider
        JointMovementLabel          matlab.ui.control.Label
        EndEffectorControlLabel     matlab.ui.control.Label
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        UpdateJointsButton          matlab.ui.control.Button
        UpdatePositionButton        matlab.ui.control.Button
        CupcakePrepLabel            matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        STARTButton                 matlab.ui.control.Button
        ESTOPButton                 matlab.ui.control.Button
        JoystickControlButton       matlab.ui.control.Button
        EResumeButton               matlab.ui.control.Button
        SetBatterButton             matlab.ui.control.Button
        LightCurtainOnButton        matlab.ui.control.Button
        SafetyDemoButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        control; % Description
        q_joint_slider = [0 0 0 0 0 0];
        x_goal;
        y_goal;
        z_goal;
        roll_goal;
        pitch_goal;
        yaw_goal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all
            %close all
            %clc
            app.control = Spawn;

            
        end

        % Button pushed function: STARTButton
        function StartEvent(app, event)
            
            
            %Cycle through scene of
            %1. Opening Door
            %2. Picking up Bottle
            %3. Populating Cupcakes
            %4. Remounting Bottle
            %5. Moving Tray into Oven
            %6. Closing Tray
            
            app.control.robot.stop_status = 0;
            app.control.robot.returnRobotJoints();
            %app.control.SpawnRMRCPoint(0.04,0.68,0.68, 0, 0, 0, 0);
            % No Movement Tied
            app.control.robot.mode=1;
            %Move to Oven Door
            fprintf('Move To Oven \n');
            app.control.SpawnRMRCPoint(-0.1,0.7,0.68, 0, -pi/2, pi/2, 5);
            pause(2);
            %Door Movement Tied
            app.control.robot.mode = 2;
            %Open Oven
            fprintf('Moving Oven \n');
            app.control.SpawnRMRCPoint(-0.1,1.02,0.47, -pi/4, -pi/2, pi/2, 5);
            pause(2);
            %No Movement Tied
            app.control.robot.mode = 1;
            fprintf('Move To Bottle \n');
            app.control.SpawnRMRCPoint(app.control.bottle_x,app.control.bottle_y+0.4,app.control.bottle_z, -pi/2, 0, pi/2, 5);
            pause(1);
            app.control.robot.mode=8;
            app.control.SpawnRMRCPoint(app.control.bottle_x,app.control.bottle_y+0.2,app.control.bottle_z, -pi/2, 0, pi/2, 2);
            %Bottle Movement Tied
            %Function for each cake movement and pause
            pause(2);
            app.control.robot.mode=3;
            fprintf('Take Bottle Out');
            app.control.SpawnRMRCPoint(app.control.tray_x,app.control.tray_y,app.control.bottle_z, -pi/2, 0, pi/2, 2)
            app.control.CakeLocationSpawn();
            fprintf('Return Bottle Location\n');
            app.control.SpawnRMRCPoint(app.control.bottle_x,app.control.bottle_y+0.2,app.control.bottle_z, -pi/2, 0, pi/2, 5);
            %No Movement Tied
            app.control.robot.mode=8;
            app.control.SpawnRMRCPoint(app.control.bottle_x,app.control.bottle_y+0.4,app.control.bottle_z, -pi/2, 0, pi/2, 2);
            pause(2);
            app.control.robot.mode=1;
            %Move Slightly out to avoid collision
            app.control.SpawnRMRCPoint(app.control.bottle_x,app.control.bottle_y+0.4,app.control.bottle_z, -pi/2, 0, pi/2, 5);
            fprintf('Move to Tray \n');
            app.control.SpawnRMRCPoint(app.control.tray_x,app.control.tray_y+0.3,app.control.tray_z, -pi/2, 0, pi, 5);
            % Tray movement tied
            pause(2);
            app.control.robot.mode=4;
            fprintf('Move Tray to Oven \n');
            app.control.SpawnRMRCPoint(0.25,0.75,0.55, -pi/2, 0, pi, 5);
            pause(2);
            %No Movement Tied
            app.control.robot.mode =1;
            %Move To Door New
            fprintf('Move To Oven \n');
            app.control.SpawnRMRCPoint(-0.15,1.02,0.47, 0, -pi/2, pi/2, 5);
            %Door Movement Tied
            app.control.robot.mode = 7;
            %Move Door
            fprintf('Moving Oven \n');
            app.control.SpawnRMRCPoint(-0.1,0.68,0.68, 0, -pi/2, pi/2, 5);
            %End
        end

        % Button pushed function: ESTOPButton
        function EmergencyStop(app, event)
            app.control.stop_status = 1;
            fprintf('Stopping \n');
            %app.control.eStop();
            %Make a certain value a certain thing
            
        end

        % Value changed function: Slot1CheckBox
        function CakeSlot1(app, event)
            value = app.Slot1CheckBox.Value;
            app.control.CakeSlotChoice(1,value);
            
        end

        % Value changed function: Slot2CheckBox
        function CakeSlot2(app, event)
            value = app.Slot2CheckBox.Value;
            app.control.CakeSlotChoice(2,value);
            
        end

        % Value changed function: Slot3CheckBox
        function CakeSlot3(app, event)
            value = app.Slot3CheckBox.Value;
            app.control.CakeSlotChoice(3,value);
            
        end

        % Value changed function: Slot4CheckBox
        function CakeSlot4(app, event)
            value = app.Slot4CheckBox.Value;
            app.control.CakeSlotChoice(4,value);
            
        end

        % Value changed function: Slot5CheckBox
        function CakeSlot5(app, event)
            value = app.Slot5CheckBox.Value;
            app.control.CakeSlotChoice(5,value);
            
        end

        % Value changed function: Slot6CheckBox
        function CakeSlot6(app, event)
            value = app.Slot6CheckBox.Value;
            app.control.CakeSlotChoice(6,value);
            
        end

        % Value changed function: Slot7CheckBox
        function CakeSlot7(app, event)
            value = app.Slot7CheckBox.Value;
            app.control.CakeSlotChoice(7,value);
            
        end

        % Value changed function: Slot8CheckBox
        function CakeSlot8(app, event)
            value = app.Slot8CheckBox.Value;
            app.control.CakeSlotChoice(8,value);
            value
        end

        % Value changed function: Slot9CheckBox
        function CakeSlot9(app, event)
            value = app.Slot9CheckBox.Value;
            app.control.CakeSlotChoice(9,value);
            
        end

        % Value changed function: Slot10CheckBox
        function CakeSlot10(app, event)
            value = app.Slot10CheckBox.Value;
            app.control.CakeSlotChoice(10,value);
            
        end

        % Value changed function: Slot11CheckBox
        function CakeSlot11(app, event)
            value = app.Slot11CheckBox.Value;
            app.control.CakeSlotChoice(11,value);
            
        end

        % Value changed function: Slot12CheckBox
        function CakeSlot12(app, event)
            value = app.Slot12CheckBox.Value;
            app.control.CakeSlotChoice(12,value);
            
        end

        % Value changed function: CupcakeColourDropDown
        function ColourChoice(app, event)
            value = app.CupcakeColourDropDown.Value;
        end

        % Callback function
        function UpdateJoint0(app, event)
            value = app.Joint0Slider.deg2rad(value);
            %N/A
        end

        % Value changed function: Joint1Slider
        function UpdateJoint1(app, event)
            value = app.Joint1Slider.Value;
            app.q_joint_slider(1,1)=deg2rad(value);
            %Make the value signify a q plan in the first point, using 6
            %points in total through the 6 sliders
        end

        % Value changed function: Joint2Slider
        function UpdateJoint2(app, event)
            value = app.Joint2Slider.Value;
            app.q_joint_slider(1,2)=deg2rad(value);
        end

        % Value changed function: Joint3Slider
        function UpdateJoint3(app, event)
            value = app.Joint3Slider.Value;
           app.q_joint_slider(1,3)=deg2rad(value);
            
        end

        % Value changed function: Joint4Slider
        function UpdateJoint4(app, event)
            value = app.Joint4Slider.Value;
            app.q_joint_slider(1,4)=deg2rad(value);
        end

        % Value changed function: Joint5Slider
        function UpdateJoint5(app, event)
            value = app.Joint5Slider.Value;
            app.q_joint_slider(1,5)=deg2rad(value);
            
        end

        % Value changed function: Joint6Slider
        function UpdateJoint6(app, event)
            value = app.Joint6Slider.Value;
            app.q_joint_slider(1,6)=deg2rad(value);
            
        end

        % Button pushed function: UpdateJointsButton
        function JointImplementUpdates(app, event)
            q_value = app.q_joint_slider
            app.control.RobotPoseAnimate(q_value);
            %app.control.robot.RMRC(q_value);
            
            T = app.control.robot.model.fkine(q_value);
            app.XEditField.Value = T(1,4);
            app.YEditField.Value = T(2,4);
            app.ZEditField.Value = T(3,4);
            eulZYX = rotm2eul(tform2rotm(T));
            %app.RollEditField = eulZYX(1,1);
            %app.PitchEditField = eulZYX(1,2);
            %app.YawEditField = eulZYX(1,3);

        end

        % Value changed function: XEditField
        function EndEffectorXAim(app, event)
            value = app.XEditField.Value;
            app.x_goal = value;
        end

        % Value changed function: YEditField
        function EndEffectorYAim(app, event)
            value = app.YEditField.Value;
            app.y_goal = value;
        end

        % Value changed function: ZEditField
        function EndEffectorZAim(app, event)
            value = app.ZEditField.Value;
            app.z_goal = value;
        end

        % Button pushed function: JoystickControlButton
        function JoyConControl(app, event)
            %Activate content similar to Lab week 11
            app.control.robot.FreeMovement();
        end

        % Button pushed function: EResumeButton
        function EResumeButtonPushed(app, event)
            app.control.eStopResume()
            %app.control.SpawnRMRCPoint(app.control.tray_x,app.control.tray_y+0.55,app.control.tableZ+0.05, -pi/2, 0, 0, 5);

            fprintf('Resume Button Activate \n');
            
        end

        % Button pushed function: UpdatePositionButton
        function UpdatePositionButtonPushed(app, event)
            %use app.xyz_goal to get a q value
            %Then call app.control.robot.RMRC(q);
            app.control.SpawnRMRCPoint(app.x_goal, app.y_goal, app.z_goal, 0, 0, 0, 4);
            
            %Alternatively derive q joints from these values
        end

        % Callback function
        function Joint7SliderValueChanged(app, event)
            value = app.Joint7Slider.Value;
            app.q_joint_slider(1,7)=deg2rad(value);

        end

        % Callback function
        function RollEditFieldValueChanged(app, event)
            value = app.RollEditField.Value;
            app.roll_goal = value;
        end

        % Callback function
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;
            app.pitch_goal = value;
        end

        % Callback function
        function YawEditFieldValueChanged(app, event)
            value = app.YawEditField.Value;
            app.yaw_goal = value;
        end

        % Button pushed function: SetBatterButton
        function SetBatterButtonPushed(app, event)
            value = app.CupcakeColourDropDown.Value;
            if (strcmp(app.CupcakeColourDropDown.Value, 'Yellow'))
                app.control.ColourChoice('yellow');
            end
            if (strcmp(app.CupcakeColourDropDown.Value, 'Red'))
                app.control.ColourChoice('red');
            end
            if (strcmp(app.CupcakeColourDropDown.Value, 'Blue'))
                app.control.ColourChoice('blue');
            end
            fprintf('Event');
            %if yellow 1, if blue 2, if green 3 etc
            %control.PaperColourChoice(colour);
        end

        % Button pushed function: LightCurtainOnButton
        function LightCurtainOnButtonPushed(app, event)
            %Put Light Curtain In
            %% Light Curtain
            planeXntersect1 = 1.3;
            planeBounds = [planeXntersect1-eps,planeXntersect1+eps,0,1.4,0,1.5];
            [Y,Z] = meshgrid(planeBounds(3):0.1:planeBounds(4),planeBounds(5):0.1:planeBounds(6));
            X = repmat(planeXntersect1,size(Y,1),size(Y,2));
            s = meshz(X,Y,Z,'FaceAlpha',0.3);
            
            planeXntersect2 = -2.6;
            planeBounds = [planeXntersect2-eps,planeXntersect2+eps,0,1.4,0,1.5];
            [Y,Z] = meshgrid(planeBounds(3):0.1:planeBounds(4),planeBounds(5):0.1:planeBounds(6));
            X = repmat(planeXntersect2,size(Y,1),size(Y,2));
            s = meshz(X,Y,Z,'FaceAlpha',0.3)
            
            planeXntersect = 1.4;
            planeBounds = [planeXntersect-eps,planeXntersect+eps,-2.6,1.3,0,1.5];
            [X,Z] = meshgrid(planeBounds(3):0.1:planeBounds(4),planeBounds(5):0.1:planeBounds(6));
            Y = repmat(planeXntersect,size(X,1),size(X,2));
            s = meshz(X,Y,Z,'FaceAlpha',0.3)
        end

        % Button pushed function: SafetyDemoButton
        function SafetyDemoButtonPushed(app, event)
            [f,v,data] = plyread('Scotty.ply','tri');
            ScottyVertexCount = size(v,1);
            
            % Move center point to origin
            midPoint = sum(v)/ScottyVertexCount;
            ScottyVerts = v - repmat(midPoint,ScottyVertexCount,1);
            
            % Create a transform to describe the location (at the origin, since it's centered
            ScottyPose = transl(0,2,0.5);
            vertexColours = [data.vertex.red, data.vertex.green, data.vertex.blue] / 255;
            
            % Then plot the trisurf
            ScottyMesh_h = trisurf(f,ScottyVerts(:,1),ScottyVerts(:,2), ScottyVerts(:,3) ...
                ,'FaceVertexCData',vertexColours,'EdgeColor','interp','EdgeLighting','flat');
            
            % Move forwards (facing in -y direction)
            forwardTR = makehgtform('translate',[0,-0.01,0]);
            ScottyPose = ScottyPose * forwardTR
            updatedPoints = [ScottyPose * [ScottyVerts,ones(ScottyVertexCount,1)]']';
            
            % Now update the Vertices
            ScottyMesh_h.Vertices = updatedPoints(:,1:3)
            
            % Now move it many times
            while 1
                pose1 = transl(0, 1.5,0.85);
                steps = 75;
                gripperInitial = zeros(1,6);
                variable = app.control.robot.model.ikcon(pose1);
                gripperInitial = [gripperInitial;variable];
                gp7qmatrix = jtraj(gripperInitial(1,:),gripperInitial(2,:),steps);
                
                for i=1:steps
                    animate(app.control.robot.model, gp7qmatrix(i,:));
                    drawnow();
                    ScottyPose = ScottyPose * forwardTR
                    updatedPoints = [ScottyPose * [ScottyVerts,ones(ScottyVertexCount,1)]']';
                    % Update the mesh vertices in the patch handle
                    ScottyMesh_h.Vertices = updatedPoints(:,1:3);
                    
                    if ScottyPose(2,4) <= 1.6;
                        disp('WatchOut!')
                        return
                    end
                    
                    
                    drawnow();
                end
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Slot1CheckBox = uicheckbox(app.UIFigure);
            app.Slot1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot1, true);
            app.Slot1CheckBox.Text = 'Slot 1';
            app.Slot2CheckBox = uicheckbox(app.UIFigure);
            app.Slot2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot2, true);
            app.Slot2CheckBox.Text = 'Slot 2';
            app.Slot3CheckBox = uicheckbox(app.UIFigure);
            app.Slot3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot3, true);
            app.Slot3CheckBox.Text = 'Slot 3';
            app.Slot4CheckBox = uicheckbox(app.UIFigure);
            app.Slot4CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot4, true);
            app.Slot4CheckBox.Text = 'Slot 4';
            app.Slot5CheckBox = uicheckbox(app.UIFigure);
            app.Slot5CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot5, true);
            app.Slot5CheckBox.Text = 'Slot 5';
            app.Slot6CheckBox = uicheckbox(app.UIFigure);
            app.Slot6CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot6, true);
            app.Slot6CheckBox.Text = 'Slot 6';
            app.Slot7CheckBox = uicheckbox(app.UIFigure);
            app.Slot7CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot7, true);
            app.Slot7CheckBox.Text = 'Slot 7';
            app.Slot8CheckBox = uicheckbox(app.UIFigure);
            app.Slot8CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot8, true);
            app.Slot8CheckBox.Text = 'Slot 8';
            app.Slot9CheckBox = uicheckbox(app.UIFigure);
            app.Slot9CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot9, true);
            app.Slot9CheckBox.Text = 'Slot 9';
            app.Slot10CheckBox = uicheckbox(app.UIFigure);
            app.Slot10CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot10, true);
            app.Slot10CheckBox.Text = 'Slot 10';
            app.Slot11CheckBox = uicheckbox(app.UIFigure);
            app.Slot11CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot11, true);
            app.Slot11CheckBox.Text = 'Slot 11';
            app.Slot12CheckBox = uicheckbox(app.UIFigure);
            app.Slot12CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot12, true);
            app.Slot12CheckBox.Text = 'Slot 12';
            app.CupcakeColourDropDownLabel = uilabel(app.UIFigure);
            app.CupcakeColourDropDownLabel.Text = 'Cupcake Colour';
            app.CupcakeColourDropDown = uidropdown(app.UIFigure);
            app.CupcakeColourDropDown.Items = {'Yellow', 'Red', 'Blue'};
            app.CupcakeColourDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourChoice, true);
            app.CupcakeColourDropDown.Value = 'Yellow';
            app.OvenAssitaBotLabel = uilabel(app.UIFigure);
            app.OvenAssitaBotLabel.Text = 'Oven Assita-Bot';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-170 170];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint1, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-65 145];
            app.Joint2Slider.MajorTicks = [-65 -35 0 25 55 85 115 145];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint2, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-70 190];
            app.Joint3Slider.MajorTicks = [-70 -18 0 34 86 138 190];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint3, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-190 190];
            app.Joint4Slider.MajorTicks = [-190 -114 -38 0 38 114 190];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint4, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-135 135];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint5, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint6, true);
            app.JointMovementLabel = uilabel(app.UIFigure);
            app.JointMovementLabel.Text = 'Joint Movement';
            app.EndEffectorControlLabel = uilabel(app.UIFigure);
            app.EndEffectorControlLabel.Text = 'End Effector Control';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorXAim, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorYAim, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorZAim, true);
            app.UpdateJointsButton = uibutton(app.UIFigure, 'push');
            app.UpdateJointsButton.ButtonPushedFcn = createCallbackFcn(app, @JointImplementUpdates, true);
            app.UpdateJointsButton.Text = 'Update Joints';
            app.UpdatePositionButton = uibutton(app.UIFigure, 'push');
            app.UpdatePositionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePositionButtonPushed, true);
            app.UpdatePositionButton.Text = 'Update Position';
            app.CupcakePrepLabel = uilabel(app.UIFigure);
            app.CupcakePrepLabel.Text = 'Cupcake Prep';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @StartEvent, true);
            app.STARTButton.Text = 'START';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStop, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.JoystickControlButton = uibutton(app.UIFigure, 'push');
            app.JoystickControlButton.ButtonPushedFcn = createCallbackFcn(app, @JoyConControl, true);
            app.JoystickControlButton.Text = {'Joystick'; ' Control'};
            app.EResumeButton = uibutton(app.UIFigure, 'push');
            app.EResumeButton.ButtonPushedFcn = createCallbackFcn(app, @EResumeButtonPushed, true);
            app.EResumeButton.Text = 'E-Resume';
            app.SetBatterButton = uibutton(app.UIFigure, 'push');
            app.SetBatterButton.ButtonPushedFcn = createCallbackFcn(app, @SetBatterButtonPushed, true);
            app.SetBatterButton.Text = 'Set Batter';
            app.LightCurtainOnButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainOnButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainOnButtonPushed, true);
            app.LightCurtainOnButton.Text = 'Light Curtain On';
            app.SafetyDemoButton = uibutton(app.UIFigure, 'push');
            app.SafetyDemoButton.ButtonPushedFcn = createCallbackFcn(app, @SafetyDemoButtonPushed, true);
            app.SafetyDemoButton.Text = 'Safety Demo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oven_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end